classdef ProyectoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExaminarButton_2              matlab.ui.control.Button
        AudioLabel                    matlab.ui.control.Label
        Banda2ListBoxLabel            matlab.ui.control.Label
        Banda2ListBox                 matlab.ui.control.ListBox
        GananciadefiltrosSliderLabel  matlab.ui.control.Label
        GananciadefiltrosSlider       matlab.ui.control.Slider
        ReproducirarchivoButton       matlab.ui.control.Button
        GenerarAudioButton            matlab.ui.control.Button
        TituloEditFieldLabel          matlab.ui.control.Label
        TituloEditField               matlab.ui.control.EditField
        GenerarmatButton              matlab.ui.control.Button
        EfectosPanel                  matlab.ui.container.Panel
        EfectoCheckBox                matlab.ui.control.CheckBox
        EfectoCheckBox_2              matlab.ui.control.CheckBox
        EfectoCheckBox_3              matlab.ui.control.CheckBox
        EfectoCheckBox_4              matlab.ui.control.CheckBox
        EfectoCheckBox_5              matlab.ui.control.CheckBox
        EfectoCheckBox_6              matlab.ui.control.CheckBox
        EfectoCheckBox_7              matlab.ui.control.CheckBox
        EfectoCheckBox_8              matlab.ui.control.CheckBox
        EfectoCheckBox_9              matlab.ui.control.CheckBox
        FreqaltaEditFieldLabel        matlab.ui.control.Label
        FreqaltaEditField             matlab.ui.control.NumericEditField
        FreqbajaEditFieldLabel        matlab.ui.control.Label
        Banda1Label                   matlab.ui.control.Label
        Banda2Label                   matlab.ui.control.Label
        FreqbajaEditField_2Label      matlab.ui.control.Label
        FreqbajaEditField_2           matlab.ui.control.NumericEditField
        FreqaltaEditField_2           matlab.ui.control.NumericEditField
        FreqaltaEditField_2Label      matlab.ui.control.Label
        AplicarEfectosButton          matlab.ui.control.Button
        AplicarEfectosButton_2        matlab.ui.control.Button
        ReproducircambiosButton       matlab.ui.control.Button
        Lamp_2                        matlab.ui.control.Lamp
        Lamp                          matlab.ui.control.Lamp
        FreqbajaEditField             matlab.ui.control.NumericEditField
        EfectoCheckBox_10             matlab.ui.control.CheckBox
        EfectoCheckBox_11             matlab.ui.control.CheckBox
        EfectoCheckBox_12             matlab.ui.control.CheckBox
        EfectosPanel_2                matlab.ui.container.Panel
        EfectoCheckBox_13             matlab.ui.control.CheckBox
        EfectoCheckBox_14             matlab.ui.control.CheckBox
        EfectoCheckBox_15             matlab.ui.control.CheckBox
        EfectoCheckBox_16             matlab.ui.control.CheckBox
        EfectoCheckBox_17             matlab.ui.control.CheckBox
        EfectoCheckBox_18             matlab.ui.control.CheckBox
        EfectoCheckBox_19             matlab.ui.control.CheckBox
        EfectoCheckBox_20             matlab.ui.control.CheckBox
        EfectoCheckBox_21             matlab.ui.control.CheckBox
        EfectoCheckBox_22             matlab.ui.control.CheckBox
        EfectoCheckBox_23             matlab.ui.control.CheckBox
        EfectoCheckBox_24             matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExaminarButton_2
        function ExaminarButton_2Pushed(app, event)
            global ruta;
            global archivo;
            global fileSelected;
            fileSelected = 0;
            [archivo, ruta] = uigetfile(".wav");
            if isequal(archivo,0)
                % f
                app.AudioLabel.Text = "<Audio>";                
                fileSelected = 0;
            else
                app.AudioLabel.Text = archivo;
                fileSelected = 1;
            end
            
        end

        % Button pushed function: ReproducirarchivoButton
        function ReproducirarchivoButtonPushed(app, event)
            global songKeeper;
            global Fs;
            global archivo;
            global ruta;
            global fileSelected;
            if isequal(fileSelected, 1)
                dirSong = join ([ruta archivo], "");
                [songKeeper, Fs] = audioread(dirSong);
                soundsc(songKeeper, Fs);
            end
        end

        % Value changing function: GananciadefiltrosSlider
        function GananciadefiltrosSliderValueChanging(app, event)
            global gainValue
            gainValue = event.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ExaminarButton_2 = uibutton(app.UIFigure, 'push');
            app.ExaminarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExaminarButton_2Pushed, true);
            app.ExaminarButton_2.Text = 'Examinar';
            app.AudioLabel = uilabel(app.UIFigure);
            app.AudioLabel.Text = '<Audio>';
            app.Banda2ListBoxLabel = uilabel(app.UIFigure);
            app.Banda2ListBoxLabel.Text = 'Banda 2';
            app.Banda2ListBox = uilistbox(app.UIFigure);
            app.GananciadefiltrosSliderLabel = uilabel(app.UIFigure);
            app.GananciadefiltrosSliderLabel.Text = 'Ganancia de filtros';
            app.GananciadefiltrosSlider = uislider(app.UIFigure);
            app.GananciadefiltrosSlider.Limits = [0 1];
            app.GananciadefiltrosSlider.ValueChangingFcn = createCallbackFcn(app, @GananciadefiltrosSliderValueChanging, true);
            app.ReproducirarchivoButton = uibutton(app.UIFigure, 'push');
            app.ReproducirarchivoButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirarchivoButtonPushed, true);
            app.ReproducirarchivoButton.Text = 'Reproducir archivo';
            app.GenerarAudioButton = uibutton(app.UIFigure, 'push');
            app.GenerarAudioButton.Text = 'Generar Audio';
            app.TituloEditFieldLabel = uilabel(app.UIFigure);
            app.TituloEditFieldLabel.Text = 'Titulo';
            app.TituloEditField = uieditfield(app.UIFigure, 'text');
            app.GenerarmatButton = uibutton(app.UIFigure, 'push');
            app.GenerarmatButton.Text = 'Generar .mat';
            app.EfectosPanel = uipanel(app.UIFigure);
            app.EfectosPanel.Title = 'Efectos';
            app.EfectoCheckBox = uicheckbox(app.EfectosPanel);
            app.EfectoCheckBox.Text = 'Efecto';
            app.EfectoCheckBox_2 = uicheckbox(app.EfectosPanel);
            app.EfectoCheckBox_2.Text = 'Efecto';
            app.EfectoCheckBox_3 = uicheckbox(app.EfectosPanel);
            app.EfectoCheckBox_3.Text = 'Efecto';
            app.EfectoCheckBox_4 = uicheckbox(app.EfectosPanel);
            app.EfectoCheckBox_4.Text = 'Efecto';
            app.EfectoCheckBox_5 = uicheckbox(app.EfectosPanel);
            app.EfectoCheckBox_5.Text = 'Efecto';
            app.EfectoCheckBox_6 = uicheckbox(app.EfectosPanel);
            app.EfectoCheckBox_6.Text = 'Efecto';
            app.EfectoCheckBox_7 = uicheckbox(app.EfectosPanel);
            app.EfectoCheckBox_7.Text = 'Efecto';
            app.EfectoCheckBox_8 = uicheckbox(app.EfectosPanel);
            app.EfectoCheckBox_8.Text = 'Efecto';
            app.EfectoCheckBox_9 = uicheckbox(app.EfectosPanel);
            app.EfectoCheckBox_9.Text = 'Efecto';
            app.EfectoCheckBox_10 = uicheckbox(app.EfectosPanel);
            app.EfectoCheckBox_10.Text = 'Efecto';
            app.EfectoCheckBox_11 = uicheckbox(app.EfectosPanel);
            app.EfectoCheckBox_11.Text = 'Efecto';
            app.EfectoCheckBox_12 = uicheckbox(app.EfectosPanel);
            app.EfectoCheckBox_12.Text = 'Efecto';
            app.EfectosPanel_2 = uipanel(app.UIFigure);
            app.EfectosPanel_2.Title = 'Efectos';
            app.EfectoCheckBox_13 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_13.Text = 'Efecto';
            app.EfectoCheckBox_14 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_14.Text = 'Efecto';
            app.EfectoCheckBox_15 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_15.Text = 'Efecto';
            app.EfectoCheckBox_16 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_16.Text = 'Efecto';
            app.EfectoCheckBox_17 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_17.Text = 'Efecto';
            app.EfectoCheckBox_18 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_18.Text = 'Efecto';
            app.EfectoCheckBox_19 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_19.Text = 'Efecto';
            app.EfectoCheckBox_20 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_20.Text = 'Efecto';
            app.EfectoCheckBox_21 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_21.Text = 'Efecto';
            app.EfectoCheckBox_22 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_22.Text = 'Efecto';
            app.EfectoCheckBox_23 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_23.Text = 'Efecto';
            app.EfectoCheckBox_24 = uicheckbox(app.EfectosPanel_2);
            app.EfectoCheckBox_24.Text = 'Efecto';
            app.FreqaltaEditFieldLabel = uilabel(app.UIFigure);
            app.FreqaltaEditFieldLabel.Text = 'Freq. alta';
            app.FreqaltaEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreqbajaEditFieldLabel = uilabel(app.UIFigure);
            app.FreqbajaEditFieldLabel.Text = 'Freq. baja';
            app.FreqbajaEditField = uieditfield(app.UIFigure, 'numeric');
            app.Banda1Label = uilabel(app.UIFigure);
            app.Banda1Label.Text = 'Banda 1';
            app.Banda2Label = uilabel(app.UIFigure);
            app.Banda2Label.Text = 'Banda 2';
            app.FreqbajaEditField_2Label = uilabel(app.UIFigure);
            app.FreqbajaEditField_2Label.Text = 'Freq. baja';
            app.FreqbajaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FreqaltaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FreqaltaEditField_2Label = uilabel(app.UIFigure);
            app.FreqaltaEditField_2Label.Text = 'Freq. alta';
            app.AplicarEfectosButton = uibutton(app.UIFigure, 'push');
            app.AplicarEfectosButton.Text = 'Aplicar Efectos';
            app.AplicarEfectosButton_2 = uibutton(app.UIFigure, 'push');
            app.AplicarEfectosButton_2.Text = 'Aplicar Efectos';
            app.ReproducircambiosButton = uibutton(app.UIFigure, 'push');
            app.ReproducircambiosButton.Text = 'Reproducir cambios';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end